classdef Application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ImageAxes                 matlab.ui.control.UIAxes
        LoadButton                matlab.ui.control.Button
        ImageAxes_2               matlab.ui.control.UIAxes
        ColormatchPanel           matlab.ui.container.Panel
        UseofcolormatchSwitch     matlab.ui.control.Switch
        ToleranceEditFieldLabel   matlab.ui.control.Label
        ToleranceEditField        matlab.ui.control.NumericEditField
        EdgeHSVPanel              matlab.ui.container.Panel
        UseofedgedetectSwitch     matlab.ui.control.Switch
        FilterSizeEditFieldLabel  matlab.ui.control.Label
        FilterSizeEditField       matlab.ui.control.NumericEditField
        SigmaEditFieldLabel       matlab.ui.control.Label
        SigmaEditField            matlab.ui.control.NumericEditField
        TresholdEditFieldLabel    matlab.ui.control.Label
        TresholdEditField         matlab.ui.control.NumericEditField
        BordercheckButtonGroup    matlab.ui.container.ButtonGroup
        Button                    matlab.ui.control.RadioButton
        Button_2                  matlab.ui.control.RadioButton
        AddButton                 matlab.ui.control.Button
        RemoveLastButton          matlab.ui.control.Button
        UpdateButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        Image % Description
        PosX = []
        PosY = []
    end
    
    methods (Access = private)
        function updateimage(app,imagefile)
           
            try
                [im,amap] = imread(imagefile);
                
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end
            if ~isempty(amap)
                im  =  ind2rgb(im,amap);          %we have a indexed image and convert it to rgb
            end
             app.Image = im;
             imagesc(app.ImageAxes,im);
        end
        
        function chosePoint(app)
            close all;
            im = app.Image;
            % For corn.tif, read the second image in the file
            [m, n, ~] = size(im);
%             im = double(im);
            figure;
            imshow(im)
            [r, c] = ginput(1);
%             ed = edge(rgb2gray(im));
%             if max(max(max(im))) <= 1
%                 im = im*255;
%             end
%             for i = 1 : m
%                 for j = 1 : n
%                     if ed(i,j)==1
%                         k = i;
%                         p = j;
%                         res(i,j,1) = 1;
%                         res(i,j,2) = 0;
%                         res(i,j,3) = 0;
%                     end
%                 end
%             end
            app.PosX(end+1)=round(c');
            app.PosY(end+1)=round(r');
            
            HSVparam = [app.FilterSizeEditField.Value, app.SigmaEditField.Value, app.TresholdEditField.Value];
            selectedIm = SuperMagicWand(im, app.PosX, app.PosY, app.ToleranceEditField.Value, HSVparam, str2double(app.BordercheckButtonGroup.SelectedObject.Text), strcmp(app.UseofcolormatchSwitch.Value,'On'), strcmp(app.UseofedgedetectSwitch.Value,'On'));

            %img = magicwand(im, round(c'), round(r'), 50);
            %             bw = roipoly(im, r,c);
%             res = zeros(m,n,3);
%             
%             for i = 1 : m
%                 for j = 1 : n
%                     if bw(i,j)==1
%                         res(i,j,1) = double(im(i,j,1))/255;
%                         res(i,j,2) = double(im(i,j,2))/255;
%                         res(i,j,3) = double(im(i,j,3))/255;
%                     end
%                 end
%             end
            %saveFileApp('tototototttotottto',res);
            imagesc(app.ImageAxes_2,selectedIm);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Callback function
        function DropDownValueChanged(app, event)
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                fname = [p f];
                updateimage(app, fname);
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            chosePoint(app);
        end

        % Button pushed function: RemoveLastButton
        function RemoveLastButtonPushed(app, event)
            app.PosX = app.PosX(1:end-1);
            app.PosY = app.PosY(1:end-1);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            HSVparam = [app.FilterSizeEditField.Value, app.SigmaEditField.Value, app.TresholdEditField.Value];
            selectedIm = SuperMagicWand(app.Image, app.PosX, app.PosY, app.ToleranceEditField.Value, HSVparam, str2double(app.BordercheckButtonGroup.SelectedObject.Text), strcmp(app.UseofcolormatchSwitch.Value,'On'), strcmp(app.UseofedgedetectSwitch.Value,'On'));
            imagesc(app.ImageAxes_2,selectedIm);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Custom Image';
            app.ImageAxes_2 = uiaxes(app.UIFigure);
            app.ImageAxes_2.XTick = [];
            app.ImageAxes_2.XTickLabel = {'[ ]'};
            app.ImageAxes_2.YTick = [];
            app.ColormatchPanel = uipanel(app.UIFigure);
            app.ColormatchPanel.Title = 'Color match';
            app.UseofcolormatchSwitch = uiswitch(app.ColormatchPanel, 'slider');
            app.ToleranceEditFieldLabel = uilabel(app.ColormatchPanel);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.ColormatchPanel, 'numeric');
            app.ToleranceEditField.Value = 140;
            app.EdgeHSVPanel = uipanel(app.UIFigure);
            app.EdgeHSVPanel.Title = 'Edge HSV';
            app.UseofedgedetectSwitch = uiswitch(app.EdgeHSVPanel, 'slider');
            app.FilterSizeEditFieldLabel = uilabel(app.EdgeHSVPanel);
            app.FilterSizeEditFieldLabel.Text = 'FilterSize';
            app.FilterSizeEditField = uieditfield(app.EdgeHSVPanel, 'numeric');
            app.FilterSizeEditField.Value = 11;
            app.SigmaEditFieldLabel = uilabel(app.EdgeHSVPanel);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.EdgeHSVPanel, 'numeric');
            app.SigmaEditField.Value = 5;
            app.TresholdEditFieldLabel = uilabel(app.EdgeHSVPanel);
            app.TresholdEditFieldLabel.Text = 'Treshold';
            app.TresholdEditField = uieditfield(app.EdgeHSVPanel, 'numeric');
            app.TresholdEditField.Value = 0.0009;
            app.BordercheckButtonGroup = uibuttongroup(app.UIFigure);
            app.BordercheckButtonGroup.Title = 'Border check';
            app.Button = uiradiobutton(app.BordercheckButtonGroup);
            app.Button.Text = '4';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.BordercheckButtonGroup);
            app.Button_2.Text = '8';
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.RemoveLastButton = uibutton(app.UIFigure, 'push');
            app.RemoveLastButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveLastButtonPushed, true);
            app.RemoveLastButton.Text = 'Remove Last';
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end