classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Button                      matlab.ui.control.Button
        BordercheckButtonGroup      matlab.ui.container.ButtonGroup
        Button_2                    matlab.ui.control.RadioButton
        Button_3                    matlab.ui.control.RadioButton
        ColormatchPanel             matlab.ui.container.Panel
        UseofcolormatchSwitch       matlab.ui.control.Switch
        ToleranceEditFieldLabel     matlab.ui.control.Label
        ToleranceEditField          matlab.ui.control.NumericEditField
        EdgeRGBPanel                matlab.ui.container.Panel
        UseofedgedetectSwitch       matlab.ui.control.Switch
        FilterSizeEditFieldLabel    matlab.ui.control.Label
        FilterSizeEditField         matlab.ui.control.NumericEditField
        SigmaEditFieldLabel         matlab.ui.control.Label
        SigmaEditField              matlab.ui.control.NumericEditField
        TresholdEditFieldLabel      matlab.ui.control.Label
        TresholdEditField           matlab.ui.control.NumericEditField
        EdgeHSVPanel                matlab.ui.container.Panel
        UseofedgedetectSwitch_2     matlab.ui.control.Switch
        FilterSizeEditField_2Label  matlab.ui.control.Label
        FilterSizeEditField_2       matlab.ui.control.NumericEditField
        SigmaEditField_2Label       matlab.ui.control.Label
        SigmaEditField_2            matlab.ui.control.NumericEditField
        TresholdEditField_2Label    matlab.ui.control.Label
        TresholdEditField_2         matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            im = imread('totoro.jpg');
            RGBparam = [app.FilterSizeEditField.Value, app.SigmaEditField.Value, app.TresholdEditField.Value];
            HSVparam = [app.FilterSizeEditField_2.Value, app.SigmaEditField_2.Value, app.TresholdEditField_2.Value];
            SuperMagicWand(im, [450], [720], app.ToleranceEditField.Value, RGBparam, HSVparam, str2double(app.BordercheckButtonGroup.SelectedObject.Text), strcmp(app.UseofcolormatchSwitch.Value,'On'), strcmp(app.UseofedgedetectSwitch.Value,'On'), strcmp(app.UseofedgedetectSwitch_2.Value,'On'));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.BordercheckButtonGroup = uibuttongroup(app.UIFigure);
            app.BordercheckButtonGroup.Title = 'Border check';
            app.Button_2 = uiradiobutton(app.BordercheckButtonGroup);
            app.Button_2.Text = '4';
            app.Button_2.Value = true;
            app.Button_3 = uiradiobutton(app.BordercheckButtonGroup);
            app.Button_3.Text = '8';
            app.ColormatchPanel = uipanel(app.UIFigure);
            app.ColormatchPanel.Title = 'Color match';
            app.UseofcolormatchSwitch = uiswitch(app.ColormatchPanel, 'slider');
            app.ToleranceEditFieldLabel = uilabel(app.ColormatchPanel);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.ColormatchPanel, 'numeric');
            app.ToleranceEditField.Value = 140;
            app.EdgeRGBPanel = uipanel(app.UIFigure);
            app.EdgeRGBPanel.Title = 'Edge RGB';
            app.UseofedgedetectSwitch = uiswitch(app.EdgeRGBPanel, 'slider');
            app.FilterSizeEditFieldLabel = uilabel(app.EdgeRGBPanel);
            app.FilterSizeEditFieldLabel.Text = 'FilterSize';
            app.FilterSizeEditField = uieditfield(app.EdgeRGBPanel, 'numeric');
            app.FilterSizeEditField.Value = 21;
            app.SigmaEditFieldLabel = uilabel(app.EdgeRGBPanel);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.EdgeRGBPanel, 'numeric');
            app.SigmaEditField.Value = 10;
            app.TresholdEditFieldLabel = uilabel(app.EdgeRGBPanel);
            app.TresholdEditFieldLabel.Text = 'Treshold';
            app.TresholdEditField = uieditfield(app.EdgeRGBPanel, 'numeric');
            app.TresholdEditField.Value = 0.02;
            app.EdgeHSVPanel = uipanel(app.UIFigure);
            app.EdgeHSVPanel.Title = 'Edge HSV';
            app.UseofedgedetectSwitch_2 = uiswitch(app.EdgeHSVPanel, 'slider');
            app.FilterSizeEditField_2Label = uilabel(app.EdgeHSVPanel);
            app.FilterSizeEditField_2Label.Text = 'FilterSize';
            app.FilterSizeEditField_2 = uieditfield(app.EdgeHSVPanel, 'numeric');
            app.FilterSizeEditField_2.Value = 11;
            app.SigmaEditField_2Label = uilabel(app.EdgeHSVPanel);
            app.SigmaEditField_2Label.Text = 'Sigma';
            app.SigmaEditField_2 = uieditfield(app.EdgeHSVPanel, 'numeric');
            app.SigmaEditField_2.Value = 5;
            app.TresholdEditField_2Label = uilabel(app.EdgeHSVPanel);
            app.TresholdEditField_2Label.Text = 'Treshold';
            app.TresholdEditField_2 = uieditfield(app.EdgeHSVPanel, 'numeric');
            app.TresholdEditField_2.Value = 0.0009;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end